classdef app1_copy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UserDropDownLabel        matlab.ui.control.Label
        UserDropDown             matlab.ui.control.DropDown
        ExperimentDropDownLabel  matlab.ui.control.Label
        ExperimentDropDown       matlab.ui.control.DropDown
        DateandtimeLabel         matlab.ui.control.Label
        SavefileEditFieldLabel   matlab.ui.control.Label
        SavefileEditField        matlab.ui.control.EditField
        MouseIDDropDownLabel     matlab.ui.control.Label
        MouseIDDropDown          matlab.ui.control.DropDown
        AgeLabel                 matlab.ui.control.Label
        SexLabel                 matlab.ui.control.Label
        GenotypeLabel            matlab.ui.control.Label
        GroupLabel               matlab.ui.control.Label
        UITable                  matlab.ui.control.Table
    end

    
    properties (Access = private)
        group
        age
        sex
        genotype
    end
    
  
    methods (Access = private)
        
        function results = read_spreadsheet(app)
            %load spreadsheet
            base_folder = fileparts(mfilename('fullpath'));
            spreadsheet_filename = fullfile(base_folder, 'data_MMC.xlsx');
           
            %extract values from spreadsheet 
            [~,name] = xlsread(spreadsheet_filename, "User", 'A2:A100');
            [~,exp] = xlsread(spreadsheet_filename, "Experiment", 'A2:A100');
            mouseID = xlsread(spreadsheet_filename, "Mouse", 'A2:A100');
            smouseID = string(mouseID);
            
            group = xlsread(spreadsheet_filename, "Mouse", 'B2:B100');
            sgroup = string(group);
            
            [~,sage] = xlsread(spreadsheet_filename, "Mouse", 'C2:C100');
            [~,ssex] = xlsread(spreadsheet_filename, "Mouse", 'D2:D100');
            [~,sgenotype] = xlsread(spreadsheet_filename, "Mouse", 'E2:E100');
                
            app.group = sgroup;
            app.age = sage;
            app.sex = ssex;
            app.genotype = sgenotype;
           
            
            %values to app variables 
            app.UserDropDown.Items = name;
            app.ExperimentDropDown.Items = exp;
            app.MouseIDDropDown.Items = smouseID;
           
            
            %set up dropdown index 
            app.MouseIDDropDown.ItemsData = 1:200;
            
        end
        
     
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            read_spreadsheet(app);
            
        end

        % Value changed function: MouseIDDropDown
        function SelectID(app, event)
            value = app.MouseIDDropDown.Value;


            app.GroupLabel.Text = app.group(value);
            app.AgeLabel.Text = app.age(value); 
            app.SexLabel.Text = app.sex(value);
            app.GenotypeLabel.Text = app.genotype(value);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UserDropDownLabel = uilabel(app.UIFigure);
            app.UserDropDownLabel.Text = 'User';
            app.UserDropDown = uidropdown(app.UIFigure);
            app.ExperimentDropDownLabel = uilabel(app.UIFigure);
            app.ExperimentDropDownLabel.Text = 'Experiment';
            app.ExperimentDropDown = uidropdown(app.UIFigure);
            app.DateandtimeLabel = uilabel(app.UIFigure);
            app.DateandtimeLabel.Text = 'Date and time';
            app.SavefileEditFieldLabel = uilabel(app.UIFigure);
            app.SavefileEditFieldLabel.Text = 'Save file';
            app.SavefileEditField = uieditfield(app.UIFigure, 'text');
            app.MouseIDDropDownLabel = uilabel(app.UIFigure);
            app.MouseIDDropDownLabel.Text = 'Mouse ID ';
            app.MouseIDDropDown = uidropdown(app.UIFigure);
            app.MouseIDDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectID, true);
            app.AgeLabel = uilabel(app.UIFigure);
            app.AgeLabel.Text = 'Age';
            app.SexLabel = uilabel(app.UIFigure);
            app.SexLabel.Text = 'Sex';
            app.GenotypeLabel = uilabel(app.UIFigure);
            app.GenotypeLabel.Text = 'Genotype';
            app.GroupLabel = uilabel(app.UIFigure);
            app.GroupLabel.Text = 'Group';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Experiment name'; 'Date & Time'; 'User'; ''};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1_copy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end